classdef TDTR_analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDTRAnalysisAppUIFigure      matlab.ui.Figure
        SavesessionButton            matlab.ui.control.Button
        LoadsessionButton            matlab.ui.control.Button
        importLabel                  matlab.ui.control.Label
        AnalysisresultsPanel         matlab.ui.container.Panel
        AnalysistimedelayrangePanel  matlab.ui.container.Panel
        t_delaymaxEditField          matlab.ui.control.NumericEditField
        t_delaymaxEditFieldLabel     matlab.ui.control.Label
        t_delayminEditField          matlab.ui.control.NumericEditField
        t_delayminEditFieldLabel     matlab.ui.control.Label
        AnalysisoptionsPanel         matlab.ui.container.Panel
        UseimporteddataCheckBox      matlab.ui.control.CheckBox
        CalculateerrorbarsCheckBox   matlab.ui.control.CheckBox
        GeneratesensitivityplotsCheckBox  matlab.ui.control.CheckBox
        SampleparametersPanel        matlab.ui.container.Panel
        GetparametersfromlibraryDropDown  matlab.ui.control.DropDown
        GetparametersfromlibraryDropDownLabel  matlab.ui.control.Label
        LayerParamsTable             matlab.ui.control.Table
        NolayersDropDown             matlab.ui.control.DropDown
        NolayersDropDownLabel        matlab.ui.control.Label
        SpotparametersPanel          matlab.ui.container.Panel
        PumpspotsizeEditField        matlab.ui.control.NumericEditField
        PumpspotsizeEditFieldLabel   matlab.ui.control.Label
        ProbespotsizeEditField       matlab.ui.control.NumericEditField
        ProbespotsizeEditFieldLabel  matlab.ui.control.Label
        RunanalysisButton            matlab.ui.control.Button
        ImportdataButton             matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lambda
        C
        h
        eta % Description
        noLayers
        layersData
        selectedCellIndices
        materialslib
        experimentData
    end
    
    methods (Access = private)
        
        function importData(app)
            [files,path] = uigetfile('*.mat');
            drawnow;
            figure(app.TDTRAnalysisAppUIFigure)
            
            load(files,'Data');
            
            if isstruct(Data)
                app.experimentData = Data;
            elseif ismatrix(Data)
                app.experimentData.tdelay = Data(:,2);
                app.experimentData.Vin = Data(:,3);
                app.experimentData.Vout = Data(:,4);
                app.experimentData.Ratio = Data(:,5);
            else
                error('There appears to be a problem with the input data. Check that the .mat file contains either a struct or a matrix with necessary fields.')
            end


            if length([files]) == 1
                app.importLabel.Text = '1 file imported.';
            elseif length([files]) > 1
                app.importLabel.Text = [num2str(length([files])), ' files imported.'];
            else
                app.importLabel.Text = 'No files imported.';
            end
            
            plot(app.UIAxes,app.experimentData.tdelay*1e-12,app.experimentData.Ratio,"LineStyle","none",'Marker','o','Color','k')

        end
        
        function initLayersTable(app)
            col = zeros(app.noLayers,1);
            app.LayerParamsTable.Data = table(num2cell(col), col, col, col);
        end
        
        function updateLayersData(app)
                app.layersData = cell2mat(table2cell(app.LayerParamsTable.Data(:,2:4)));    
        end
        
        function getLayerDataFromLibrary(app, layer)
            layerParams = app.materialslib(layer,:);
            app.LayerParamsTable.Data(app.selectedCellIndices(1),:) = layerParams;
            updateLayersData(app)
            drawnow;
        end
            
        function saveSession(app)
            writetable(app.LayerParamsTable.Data, ['session_' datestr(now,'ddmmyy_hhMMss') '.txt'])
        end
        
        function loadSession(app)
            [file,path] = uigetfile('*.txt');
            drawnow;
            figure(app.TDTRAnalysisAppUIFigure)
            
            params = readtable(file);
            app.LayerParamsTable.Data = params;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             %THESE PARAMS FOR TEST DATA
            layers = {'Al'; 'Interface'; 'Substrate'};
            abslayer = 10;
            
            lambdas = [45; 0.3; 40];
            heatcaps = [2.9; 0.1; 3.36]*1e6;
            thcks = [60; 1; 1e6]*1e-9;

            %%our test sample
%             layers = {'Al'; 'SiO2'; 'Substrate'};
%             
%             lambdas = [237; 1.3; 124];
%             heatcaps = [2.4; 1.86; 1.63]*1e6;
%             thcks = [100; 1040; 1e6]*1e-9;

            app.layersData = [lambdas heatcaps thcks];
            app.LayerParamsTable.Data = table(layers, (app.layersData(:,1)), (app.layersData(:,2)), (app.layersData(:,3)));
            drawnow;
            
            app.materialslib = readtable('TDTR_MATERIAL_PARAMETERS_LIBRARY.xlsx');
            app.materialslib.Properties.RowNames = table2cell(app.materialslib(:,1));
            app.GetparametersfromlibraryDropDown.Items = table2cell(app.materialslib(:,1));
                     
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            %% These first 11 lines of code are not important
            keepdir=0;
            if keepdir==1
                save('bla.mat','filename','pathname','keepdir')
            else
                save('bla.mat','keepdir')
            end
            pause(0.1)
            load('bla.mat','filename','pathname','keepdir');
            tic %Start the timer
            
            %% -------------ENTER  THERMAL SYSTEM PARAMTERS HERE--------------
            %definitions of THERMAL SYSTEM PARAMTERS:
            %lambda: through-plane thermal conductivity (w/m-K)
            %C: heat capacity (J/m3-K)
            %h: layer thickness (m)
            %eta: anisotropic ratio, lambda_{radial} / lambda_{through-plane}
            %f:  pump modulation frequency
            %r_pump: pump 1/e^2 radius, m
            %r_probe: pump 1/e^2 radius, m
            %A_pump: pump laser power (Watts)
            %TCR:  coefficient of thermal reflectance
            %tau_rep: %laser repetition period, s (1/repetition rate)
            
            
            %Convergence of the real part ofDRMstdis also facilitatedby replacing the top 10 nm of the Al metal film at the surface
            % of the sample by a 1 nm layer that has ten times the true heat capacity and ten times the true thermal conductivity of the Al
            % film. This  mimics  the  adsorption  of  laser  energy  within  the thickness  of  the  optical  absorption  depth  without  changing
            % the  heat  capacity  per  unit  area  of  the Al  film  or  the  lateral thermal diffusivity of the Al film
            

%            abslayer = 10;
%             app.lambda = [170*abslayer 170 0.1 140]; %W/m-K %first element transducer, last substrate
%             app.C = [2.42*abslayer 2.42 0.1 1.7]*1e6; %J/m^3-K
%             app.h = [1 (80-abslayer) 1 1e6]*1e-9; %m ("Al" thickness estimated at 84nm=81nm Al+3nm oxide, from picosecond acoustic)

            updateLayersData(app)
            
            app.lambda = app.layersData(:,1)';
            app.C = app.layersData(:,2)';
            app.h = app.layersData(:,3)';
            
            app.eta = ones(1,numel(app.lambda)); %isotropic layers, eta=kx/ky;
            
            
            f = 9.5e6;%f = 10.1e6; %EOM modulation frequency, Hz
            f_mira = 75.89e6;%f_mira = 80e6; %mira pulse frequency, Hz
            
            %spot sizes, 1/e^2 radius, m
            %usually assume they are the same size, otherwise alter below
            r_pump = app.PumpspotsizeEditField.Value; %pump 1/e^2 radius, m
            r_probe = app.PumpspotsizeEditField.Value; %probe 1/e^2 radius, m

            r_pump_data = r_pump;
            
            A_pump = 10e-3; %A_pump = 30e-3; %pump laser power (Watts) . . . only used for amplitude est.
            TCR = 1e-4; %coefficient of thermal reflectance . . . only used for amplitude est.
            tau_rep = 1/f_mira; %laser repetition period, s

            X_heat  = ((1:5:36)*1e-9)';  % Temperature response is calculated for each entry i of the COLUMN vector X_heat, where X_heat(i) defines the ininitesimal surface that is being heated 
            X_temp  = 1e-9;   % depth at which temperature response is calculated (SCALAR); to consider depth sensitivity of TDTR, solve for various X_temp's through the optical skin depth and weight solutions according to the depth sensitivity (assuming linear problem)
            AbsProf = exp(-4*pi*4.9*X_heat/785e-9);  % COLUMN vector describing absorption profile through depths X_heat of the sample; does not need to be normalized
            nnodes = 35; %number of nodes used for numerical integration. Default = 35.

            %% ------------- CALCULATE STEADY STATE HEATING--------------
            absorbance = 0.1; %fraction of incident light absorbed at surface
            A_tot_powermeter = 48e-3; %incident light intensity, Watts
%             dT_SS = SS_Heating(app.lambda,app.C,app.h,app.eta,r_pump,r_probe,absorbance,A_tot_powermeter); %avg. steady state temperature rise
%             disp(['Steady state temperature rise: ', num2str(dT_SS)])
          
            %% ------------- SELECT TIME DELAYS TO SIMULATE OR FIT OVER--------------
            
            %choose time delays for Sensitivity plots
            tdelay_sense_min = app.t_delayminEditField.Value;%earliest time delay for sensitivity plots
            tdelay_sense_max = app.t_delaymaxEditField.Value;%latest time delay for sensitivity plots
            tdelay = logspace(log10(tdelay_sense_min),log10(tdelay_sense_max),20)'; %vector of time delays (used to generate sensitivity plots)
            
            %Choose range of time delays to fit data, (sec)
            tdelay_min = app.t_delayminEditField.Value; %earliest time delay for data fitting
            tdelay_max = app.t_delaymaxEditField.Value; %latest time delay for data fitting
            tdelay_model = logspace(log10(10e-12),log10(4e-9),30)'; %time delays for model curves
            
            FITNLambda = [2,3];
            FITNC = []; 
            FITNh = [];
 

            %% Make Sensitivity Plots
            if app.GeneratesensitivityplotsCheckBox.Value == 1
                
            end
            
            %% IMPORT DATA
            %--------------Import Data---------------------------
            if app.UseimporteddataCheckBox.Value==1
                
                %% IMPORT AND PREPARE DATA FOR FITTING
                tdelay_raw=app.experimentData.tdelay*1e-12; %imported in picoseconds.  Change to seconds.
                Vin_raw=app.experimentData.Vin; %Units (uV ?)
                Vout_raw=app.experimentData.Vout;
                ratio_raw=app.experimentData.Ratio; %(-Vin./Vout)
                [~,Vin_data] = extract_interior_V4(tdelay_raw,Vin_raw,tdelay_min,tdelay_max);
                [~,Vout_data] = extract_interior_V4(tdelay_raw,Vout_raw,tdelay_min,tdelay_max);
                [tdelay_data,ratio_data] = extract_interior_V4(tdelay_raw,ratio_raw,tdelay_min,tdelay_max);
                

                %% DO FITTING
                % define initial value(s) for fit
                X0 = zeros(length(FITNLambda)+length(FITNC)+length(FITNh),1);
                for i = 1:length(FITNLambda)
                    X0(i) = app.lambda(FITNLambda(i));   
                end
                for i = 1:length(FITNC)
                    X0(length(FITNLambda)+i) = app.C(FITNC(i));  
                end
                for i = 1:length(FITNh)
                    X0(length(FITNLambda)+length(FITNC)+i) = app.h(FITNh(i));
                end
                
      
                Xsol = fminsearch(@(X) TDTR_Bidirectional_SUB_C_GUI(X,ratio_data,tdelay_data,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pump_data,r_probe,P_pump,nnodes,FITNLambda,FITNC,FITNh,X_heat,X_temp,AbsProf),X0); 
                [Z,~] = TDTR_Bidirectional_SUB_C_GUI(Xsol,Ratio_data,tdelay_data,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pump_data,r_probe,P_pump,nnodes,FITNLambda,FITNC,FITNh,X_heat,X_temp,AbsProf);
                fprintf('Data fit completed\n')

                
                % assign fit results to adjusted parameters
                for i = 1:length(FITNLambda)
                    app.lambda(FITNLambda(i)) = Xsol(i);   
                end
                for i = 1:length(FITNC)
                    app.C(FITNC(i)) = Xsol(length(FITNLambda)+i);   
                end
                for i = 1:length(FITNh)
                    app.h(FITNh(i)) = Xsol(length(FITNLambda)+length(FITNC)+i);
                end
            end

            %% COMPUTE ERRORBARS
            %--------------Compute Errorbars---------------------
            if app.CalculateerrorbarsCheckBox.Value==1
                fprintf('Calculating Errobar\n')
                fprintf('YErr(n,:) = uncertainty (absolute) in X due to uncertainty in parameter Y(n)\n')
                Xsol=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pump,r_probe,A_pump, app.UIAxes),X_Fit_Variables);
                %Initial Guess, Variable to fit experiment to ... must change "TDTR_FIT"program also
                for ii=1:length(app.lambda)
                    %-------Specific Heat--------------
                    if C_consider(ii)==1
                        Ctemp=app.C;
                        Ctemp(ii)=app.C(ii)*(1+Cperc_err(ii));
                        Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,app.lambda,Ctemp,app.h,app.eta,r_pump,r_probe,A_pump, app.UIAxes),X_Fit_Variables);
                        for n=1:length(X_Fit_Variables)
                            CErr(ii,n)=abs(Xsoltemp(n)-X_Fit_Variables(n)); %Error in X(n) due to variable C(ii)
                        end
                        CErr
                    end
                    
                    %-------Thermal Conductivity--------------
                    if L_consider(ii)==1
                        lambdatemp=app.lambda;
                        lambdatemp(ii)=app.lambda(ii)*(1+lambdaperc_err(ii));
                        Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,lambdatemp,app.C,app.h,app.eta,r_pump,r_probe,A_pump, app.UIAxes),X_Fit_Variables);
                        for n=1:length(X_Fit_Variables)
                            lambdaErr(ii,n)=abs(Xsoltemp(n)-X_Fit_Variables(n)); %Error in X(n) due to variable lambda(ii)
                        end
                        lambdaErr
                    end
                    
                    %-------Layer Thickness--------------
                    if h_consider(ii)==1
                        h_temp=app.h;
                        h_temp(ii)=app.h(ii)*(1+h_perc_err(ii));
                        Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,app.lambda,app.C,h_temp,app.eta,r_pump,r_probe,A_pump, app.UIAxes),X_Fit_Variables);
                        for n=1:length(X_Fit_Variables)
                            h_Err(ii,n)=abs(Xsoltemp(n)-X_Fit_Variables(n)); %Error in X(n) due to variable h(ii)
                        end
                        h_Err
                    end
                end
                %-------Probe Radius-------------- %UPDATE Consider as simulatenous error
                %with pump since they are not independent.
                if r_probe_consider==1
                    r_probetemp=r_probe*(1+r_err);
                    r_pumptemp=r_pump*(1+r_err);
                    Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pumptemp,r_probetemp,A_pump, app.UIAxes),X_Fit_Variables);
                    for n=1:length(X_Fit_Variables)
                        r_probeErr(1,n)=abs(Xsoltemp(n)-X_Fit_Variables(n)); %Error in X(n) due to variable r_probe(ii)
                    end
                    r_probeErr
                end
                %-------Pump Radius-------------- %UPDATE Consider as same.
                % if r_pump_consider==1
                % r_pumptemp=r_pump*(1+r_err);
                % Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_data,tdelay,TCR,tau_rep,f,lambda,C,h,eta,r_pumptemp,r_probe,A_pump),X_Fit_Variables);
                % for n=1:length(X_Fit_Variables)
                %     r_pumpErr(1,n)=abs(Xsoltemp(n)-X_Fit_Variables(n)); %Error in X(n) due to variable r_pump(ii)
                % end
                % r_pumpErr
                % end
                %--------Phase Error-------------
                if phase_consider==1
                    radphase=pi/180*degphase;
                    Vtemp=(Vin_data+sqrt(-1)*Vout_data)*exp(sqrt(-1)*radphase);
                    Vin_phaseshifted=real(Vtemp);
                    Vout_phaseshifted=imag(Vtemp);
                    ratio_phaseshifted=-Vin_phaseshifted./Vout_phaseshifted;
                    Xsoltemp=fminsearch(@(X) TDTR_FIT(X,ratio_phaseshifted,tdelay,TCR,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pump,r_probe,A_pump, app.UIAxes),X_Fit_Variables);
                    for n=1:length(X_Fit_Variables)
                        phaseErr(1,n)=abs(Xsoltemp(n)-X_Fit_Variables(n));
                    end
                    phaseErr
                end
                
                ErrSummary_perc=[CErr;lambdaErr;h_Err;r_probeErr;phaseErr]./(ones(3*length(app.lambda)+2,1)*Xsol); %percent error broken by variable
    
                fprintf('Errorbar calculated\n')
                fprintf('Errorbar breakdown:\n')
                fprintf('Percent Err from C:\n')
                CErr
                fprintf('Abs Err from lambda:\n')
                lambdaErr
                fprintf('Abs Err from h:\n')
                h_Err
                fprintf('Abs Err from spot size:\n')
                r_probeErr
                fprintf('Abs Err from phase:\n')
                phaseErr
                %----------------------------------------------------
                fprintf('Total Percent Error:\n')
                kErr_perc=sqrt(sum(ErrSummary_perc.^2,1)) %total percent error in each fitted parameter
                fprintf('Total Absolute Error:\n')
                kErr_abs=kErr_perc.*Xsol %total absolute error in each fitted parameter
            end
            fprintf('Fitting Solution:\n')
            Xsol
            toc

            %% EXPORT RESULTS
%             if (app.UseimporteddataCheckBox.Value ||app.CalculateerrorbarsCheckBox.Value)
%                 [Z,ratio_model]=TDTR_FIT(Xsol,ratio_data,tdelay,TCR,tau_rep,f,app.lambda,app.C,app.h,app.eta,r_pump,r_probe,A_pump, app.UIAxes);
%                 dlmwrite('last_fit.txt',[tdelay*1e12,ratio_model],'delimiter','\t')
%                 
%                 %fignum=203;
%                 %figure(fignum)
%                 semilogx(app.UIAxes,tdelay,ratio_data,'ko',tdelay,ratio_model,'k-')
%                 xlabel(app.UIAxes,'time delay (s)')
%                 ylabel(app.UIAxes,'-Vin/Vout')
%                 legend(app.UIAxes,'experiment','model')
%                 axis(app.UIAxes, [min(tdelay) max(tdelay) 0 1.2*max([ratio_data;ratio_model])])
%             end
%             
%             %----------------------------------------------------
%             saveint=input('Want to save results?\n(0=no, 1=yes)\n');
%             if saveint==1
%                 if (importdata&&ebar)
%                 save(strcat(pathname,filename(1:end-4),'Results.mat'))
%                 print(fignum,'-depsc',strcat(pathname,filename(1:end-4),'FIT.eps'))
%                 else
%                     save(strcat('Results',datestr(now,'yyyymmddTHHMMSS'),'.mat'))
%                 end
%             end

        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            importData(app)
        end

        % Cell edit callback: LayerParamsTable
        function LayerParamsTableCellEdit(app, event)
            updateLayersData(app)
            
        end

        % Value changed function: NolayersDropDown
        function NolayersDropDownValueChanged(app, event)
            app.noLayers = app.NolayersDropDown.Value;
            initLayersTable(app)
        end

        % Cell selection callback: LayerParamsTable
        function LayerParamsTableCellSelection(app, event)
            app.selectedCellIndices = event.Indices;
            
        end

        % Value changed function: GetparametersfromlibraryDropDown
        function GetparametersfromlibraryDropDownValueChanged(app, event)
            layer = app.GetparametersfromlibraryDropDown.Value;
            getLayerDataFromLibrary(app,layer)
        end

        % Button pushed function: SavesessionButton
        function SavesessionButtonPushed(app, event)
            saveSession(app)
        end

        % Button pushed function: LoadsessionButton
        function LoadsessionButtonPushed(app, event)
            loadSession(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDTRAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.TDTRAnalysisAppUIFigure.Name = 'TDTR Analysis App';
            app.UIAxes = uiaxes(app.TDTRAnalysisAppUIFigure);
            title(app.UIAxes, 'Data/Fit')
            xlabel(app.UIAxes, 'Time delay (s)')
            ylabel(app.UIAxes, '-V_in/V_out')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.TDTRAnalysisAppUIFigure);
            title(app.UIAxes_2, 'Sensitivity plots')
            xlabel(app.UIAxes_2, 'Time delay')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ImportdataButton = uibutton(app.TDTRAnalysisAppUIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.RunanalysisButton = uibutton(app.TDTRAnalysisAppUIFigure, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.SpotparametersPanel = uipanel(app.TDTRAnalysisAppUIFigure);
            app.SpotparametersPanel.Title = 'Spot parameters';
            app.ProbespotsizeEditFieldLabel = uilabel(app.SpotparametersPanel);
            app.ProbespotsizeEditFieldLabel.Text = 'Probe spot size';
            app.ProbespotsizeEditField = uieditfield(app.SpotparametersPanel, 'numeric');
            app.ProbespotsizeEditField.Value = 1e-05;
            app.PumpspotsizeEditFieldLabel = uilabel(app.SpotparametersPanel);
            app.PumpspotsizeEditFieldLabel.Text = 'Pump spot size';
            app.PumpspotsizeEditField = uieditfield(app.SpotparametersPanel, 'numeric');
            app.PumpspotsizeEditField.Value = 1e-05;
            app.SampleparametersPanel = uipanel(app.TDTRAnalysisAppUIFigure);
            app.SampleparametersPanel.Title = 'Sample parameters';
            app.NolayersDropDownLabel = uilabel(app.SampleparametersPanel);
            app.NolayersDropDownLabel.Text = 'No. layers';
            app.NolayersDropDown = uidropdown(app.SampleparametersPanel);
            app.NolayersDropDown.Items = {'1', '2', '3', '4'};
            app.NolayersDropDown.ItemsData = [1 2 3 4];
            app.NolayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NolayersDropDownValueChanged, true);
            app.NolayersDropDown.Value = 4;
            app.LayerParamsTable = uitable(app.SampleparametersPanel);
            app.LayerParamsTable.ColumnName = {'Material'; 'Thermal conductivity (W/mK)'; 'Volumetric heat capacity (J/m^3K)'; 'Thickness (m)'};
            app.LayerParamsTable.RowName = {'1'; '2'; '3'; '4'};
            app.LayerParamsTable.ColumnEditable = true;
            app.LayerParamsTable.CellEditCallback = createCallbackFcn(app, @LayerParamsTableCellEdit, true);
            app.LayerParamsTable.CellSelectionCallback = createCallbackFcn(app, @LayerParamsTableCellSelection, true);
            app.GetparametersfromlibraryDropDownLabel = uilabel(app.SampleparametersPanel);
            app.GetparametersfromlibraryDropDownLabel.Text = 'Get parameters from library';
            app.GetparametersfromlibraryDropDown = uidropdown(app.SampleparametersPanel);
            app.GetparametersfromlibraryDropDown.ValueChangedFcn = createCallbackFcn(app, @GetparametersfromlibraryDropDownValueChanged, true);
            app.AnalysisoptionsPanel = uipanel(app.TDTRAnalysisAppUIFigure);
            app.AnalysisoptionsPanel.Title = 'Analysis options';
            app.GeneratesensitivityplotsCheckBox = uicheckbox(app.AnalysisoptionsPanel);
            app.GeneratesensitivityplotsCheckBox.Text = 'Generate sensitivity plots';
            app.GeneratesensitivityplotsCheckBox.Value = true;
            app.CalculateerrorbarsCheckBox = uicheckbox(app.AnalysisoptionsPanel);
            app.CalculateerrorbarsCheckBox.Text = 'Calculate errorbars';
            app.CalculateerrorbarsCheckBox.Value = true;
            app.UseimporteddataCheckBox = uicheckbox(app.AnalysisoptionsPanel);
            app.UseimporteddataCheckBox.Text = 'Use imported data';
            app.UseimporteddataCheckBox.Value = true;
            app.AnalysistimedelayrangePanel = uipanel(app.TDTRAnalysisAppUIFigure);
            app.AnalysistimedelayrangePanel.Title = 'Analysis time delay range';
            app.t_delayminEditFieldLabel = uilabel(app.AnalysistimedelayrangePanel);
            app.t_delayminEditFieldLabel.Text = 't_delay, min';
            app.t_delayminEditField = uieditfield(app.AnalysistimedelayrangePanel, 'numeric');
            app.t_delayminEditField.Value = 1e-10;
            app.t_delaymaxEditFieldLabel = uilabel(app.AnalysistimedelayrangePanel);
            app.t_delaymaxEditFieldLabel.Text = 't_delay, max';
            app.t_delaymaxEditField = uieditfield(app.AnalysistimedelayrangePanel, 'numeric');
            app.t_delaymaxEditField.Value = 4e-09;
            app.AnalysisresultsPanel = uipanel(app.TDTRAnalysisAppUIFigure);
            app.AnalysisresultsPanel.Title = 'Analysis results';
            app.importLabel = uilabel(app.TDTRAnalysisAppUIFigure);
            app.importLabel.Text = '';
            app.LoadsessionButton = uibutton(app.TDTRAnalysisAppUIFigure, 'push');
            app.LoadsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsessionButtonPushed, true);
            app.LoadsessionButton.Text = 'Load session';
            app.SavesessionButton = uibutton(app.TDTRAnalysisAppUIFigure, 'push');
            app.SavesessionButton.ButtonPushedFcn = createCallbackFcn(app, @SavesessionButtonPushed, true);
            app.SavesessionButton.Text = 'Save session';
            % Show the figure after all components are created
            app.TDTRAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDTR_analysis_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TDTRAnalysisAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDTRAnalysisAppUIFigure)
        end
    end
end